classdef main_FiberQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        FolderTab                      matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        AddFolderButton                matlab.ui.control.Button
        RemoveFolderButton             matlab.ui.control.Button
        AnalyseButton                  matlab.ui.control.Button
        HelpButton                     matlab.ui.control.Button
        Label_19                       matlab.ui.control.Label
        Label_20                       matlab.ui.control.Label
        GhesquiereLabel                matlab.ui.control.Label
        HugoWurteleSantiagoCostantinoTODOWHENPUBLISHEDLabel  matlab.ui.control.Label
        ParametersTab                  matlab.ui.container.Tab
        EOP2EditFieldLabel             matlab.ui.control.Label
        EOP8EditFieldLabel             matlab.ui.control.Label
        EOP9EditFieldLabel             matlab.ui.control.Label
        EOP2                           matlab.ui.control.NumericEditField
        EOP8                           matlab.ui.control.NumericEditField
        EOP9                           matlab.ui.control.NumericEditField
        DensitythresholdLabel          matlab.ui.control.Label
        MaximumsplicingdistanceLabel   matlab.ui.control.Label
        MinimumlengthforfibersaftersplicingLabel  matlab.ui.control.Label
        AdvancedParametersTab          matlab.ui.container.Tab
        SetsthesizeoftheareausedtocalculatefiberdensityLabel  matlab.ui.control.Label
        EOP1EditFieldLabel             matlab.ui.control.Label
        EOP1                           matlab.ui.control.NumericEditField
        SetsthemaximumfiberwidthLabel  matlab.ui.control.Label
        SetsthekernelsizeoftheLaplacianofGaussianfilterLabel  matlab.ui.control.Label
        Label_16                       matlab.ui.control.Label
        SettheminimumlengthforastrandLabel  matlab.ui.control.Label
        Label_17                       matlab.ui.control.Label
        EOP3EditFieldLabel             matlab.ui.control.Label
        EOP3                           matlab.ui.control.NumericEditField
        EOP4EditFieldLabel             matlab.ui.control.Label
        EOP4                           matlab.ui.control.NumericEditField
        EOP5EditFieldLabel             matlab.ui.control.Label
        EOP5                           matlab.ui.control.NumericEditField
        EOP6EditFieldLabel             matlab.ui.control.Label
        EOP6                           matlab.ui.control.NumericEditField
        EOP7EditFieldLabel             matlab.ui.control.Label
        EOP7                           matlab.ui.control.NumericEditField
        Label_18                       matlab.ui.control.Label
        EOP10Label                     matlab.ui.control.Label
        EOP10                          matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        % Button pushed function: AddFolderButton
        function AddFolderButtonPushed(app, event)
            
            dname = uigetdir(pwd);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if dname==0
                return
            end
            Select = false;
            app.UITable.ColumnFormat = {'char',{'IdU','CldU'},{'1','2'},{'green','red'},'logical'};
            
            if isempty(app.UITable.Data)
                app.UITable.Data ={dname,[],[],[],Select};
            else
                app.UITable.Data(end+1,:) ={dname,[],[],[],Select};
            end
            
        end

        % Button pushed function: RemoveFolderButton
        function RemoveFolderButtonPushed(app, event)
            ind = find(strcmp(app.UITable.ColumnName,'Select'));
            Select = find([app.UITable.Data{:,ind}]);
            app.UITable.Data(Select,:) = [];
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            inp = app.UITable.Data;
            isempt = cellfun(@isempty,inp(:,1:end-1));
            if any(isempt)
                title = 'Error';
                field2Fill = {app.UITable.ColumnName{isempt}}';
                message = 'Please fill the following field(s) : \n ';
                for i =1:numel(field2Fill)
                    message = [message '- ' field2Fill{i} ' \n '];
                end
                uialert(app.UIFigure,sprintf(message),title);
                return
            end
            EOPs.EOP1 = app.EOP1.Value;
            EOPs.EOP2 = app.EOP2.Value;
            EOPs.EOP3 = app.EOP3.Value;
            EOPs.EOP4 = app.EOP4.Value;
            EOPs.EOP5 = app.EOP5.Value;
            EOPs.EOP6 = app.EOP6.Value;
            EOPs.EOP7 = app.EOP7.Value;
            EOPs.EOP8 = app.EOP8.Value;
            EOPs.EOP9 = app.EOP9.Value;
            EOPs.EOP10 = app.EOP10.Value;
            addpath(genpath('sharedFunctions'))
            FiberQ(inp,EOPs)
        end

        % Callback function
        function UITableCellEdit(app, event)
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
        end

        % Button pushed function: HelpButton
        function helpCallBack(app, event)
            f = msgbox({'1- Click on "Add Folder" and choose the folder containing the images to segment';...
                '2- Choose the "First Pulse".  If your sample was first pulsed with IdU, choose "IdU". In this case the second pulse will automatically be set to CldU';...
                '3- Fill "Channel #" :  exemple : If your "First Pulse" is IdU and IdU corresponds to the second channel of your images, select "2" In this case, the 1st channel of your images will be considered as CldU signal.';...
                '4- Fill "Color" column : example : If your ''First Pulse'' is IdU and you want IdU signal to be green in the result images, select ''green''. In this case, CldU signal will automatically appear red in the result images.' ;...
                '(5- If you want to remove a folder, tick the corresponding box in "Select" column and click on "Remove Folder")';...
                '6- Click on "Analyse".'},'Help');
            th = findall(f, 'Type', 'Text');
            th.FontSize = 14;
            deltaWidth = sum(th.Extent([1,3]))-f.Position(3) + th.Extent(1);
            deltaHeight = sum(th.Extent([2,4]))-f.Position(4) + 10;
            f.Position([3,4]) = f.Position([3,4]) + [deltaWidth, deltaHeight];
            f.Resize='on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FolderTab = uitab(app.TabGroup);
            app.FolderTab.Title = 'Folder';
            app.UITable = uitable(app.FolderTab);
            app.UITable.ColumnName = {'Folders'; 'First Pulse'; 'Channel #'; 'Color'; 'Select'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddFolderButton = uibutton(app.FolderTab, 'push');
            app.AddFolderButton.ButtonPushedFcn = createCallbackFcn(app, @AddFolderButtonPushed, true);
            app.AddFolderButton.Text = 'Add Folder';
            app.RemoveFolderButton = uibutton(app.FolderTab, 'push');
            app.RemoveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFolderButtonPushed, true);
            app.RemoveFolderButton.Text = 'Remove Folder';
            app.AnalyseButton = uibutton(app.FolderTab, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.HelpButton = uibutton(app.FolderTab, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @helpCallBack, true);
            app.HelpButton.Text = 'Help';
            app.Label_19 = uilabel(app.FolderTab);
            app.Label_19.Text = 'Please cite the following article if you use this software : ';
            app.Label_20 = uilabel(app.FolderTab);
            app.Label_20.Text = '"An open-source algorithm for rapid unbiased determination of DNA fiber length ';
            app.GhesquiereLabel = uilabel(app.FolderTab);
            app.GhesquiereLabel.Text = 'Pierre Ghesquière, Abdelhamid Elsherbiny, Emile Fortier, Mary McQuaid, Javier Mazzaferri, François Bélanger, Farida Cheriet, Elliot Drobetsky, ';
            app.HugoWurteleSantiagoCostantinoTODOWHENPUBLISHEDLabel = uilabel(app.FolderTab);
            app.HugoWurteleSantiagoCostantinoTODOWHENPUBLISHEDLabel.Text = 'Hugo Wurtele, Santiago Costantino. TODO WHEN PUBLISHED !';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.EOP2EditFieldLabel = uilabel(app.ParametersTab);
            app.EOP2EditFieldLabel.Text = 'EOP2';
            app.EOP8EditFieldLabel = uilabel(app.ParametersTab);
            app.EOP8EditFieldLabel.Text = 'EOP8';
            app.EOP9EditFieldLabel = uilabel(app.ParametersTab);
            app.EOP9EditFieldLabel.Text = 'EOP9';
            app.EOP2 = uieditfield(app.ParametersTab, 'numeric');
            app.EOP2.Value = 0.21;
            app.EOP8 = uieditfield(app.ParametersTab, 'numeric');
            app.EOP8.Value = 11;
            app.EOP9 = uieditfield(app.ParametersTab, 'numeric');
            app.EOP9.Value = 15;
            app.DensitythresholdLabel = uilabel(app.ParametersTab);
            app.DensitythresholdLabel.Text = 'Density threshold ';
            app.MaximumsplicingdistanceLabel = uilabel(app.ParametersTab);
            app.MaximumsplicingdistanceLabel.Text = 'Maximum splicing distance ';
            app.MinimumlengthforfibersaftersplicingLabel = uilabel(app.ParametersTab);
            app.MinimumlengthforfibersaftersplicingLabel.Text = 'Minimum length for fibers after splicing';
            app.AdvancedParametersTab = uitab(app.TabGroup);
            app.AdvancedParametersTab.Title = 'Advanced Parameters';
            app.SetsthesizeoftheareausedtocalculatefiberdensityLabel = uilabel(app.AdvancedParametersTab);
            app.SetsthesizeoftheareausedtocalculatefiberdensityLabel.Text = 'Sets the size of the area used to calculate fiber density ';
            app.EOP1EditFieldLabel = uilabel(app.AdvancedParametersTab);
            app.EOP1EditFieldLabel.Text = 'EOP1';
            app.EOP1 = uieditfield(app.AdvancedParametersTab, 'numeric');
            app.EOP1.Value = 8;
            app.SetsthemaximumfiberwidthLabel = uilabel(app.AdvancedParametersTab);
            app.SetsthemaximumfiberwidthLabel.Text = 'Sets the maximum fiber width ';
            app.SetsthekernelsizeoftheLaplacianofGaussianfilterLabel = uilabel(app.AdvancedParametersTab);
            app.SetsthekernelsizeoftheLaplacianofGaussianfilterLabel.Text = 'Sets the kernel size of the Laplacian of Gaussian filter ';
            app.Label_16 = uilabel(app.AdvancedParametersTab);
            app.Label_16.Text = 'Sets the length of the structural element used for dilating Canny’s edges ';
            app.SettheminimumlengthforastrandLabel = uilabel(app.AdvancedParametersTab);
            app.SettheminimumlengthforastrandLabel.Text = 'Set the minimum length for a strand';
            app.Label_17 = uilabel(app.AdvancedParametersTab);
            app.Label_17.Text = 'Sets the spanning length of the 1st degree polynomial for smoothing fiber skeletons ';
            app.EOP3EditFieldLabel = uilabel(app.AdvancedParametersTab);
            app.EOP3EditFieldLabel.Text = 'EOP3';
            app.EOP3 = uieditfield(app.AdvancedParametersTab, 'numeric');
            app.EOP3.Value = 3.6;
            app.EOP4EditFieldLabel = uilabel(app.AdvancedParametersTab);
            app.EOP4EditFieldLabel.Text = 'EOP4';
            app.EOP4 = uieditfield(app.AdvancedParametersTab, 'numeric');
            app.EOP4.Value = 1;
            app.EOP5EditFieldLabel = uilabel(app.AdvancedParametersTab);
            app.EOP5EditFieldLabel.Text = 'EOP5';
            app.EOP5 = uieditfield(app.AdvancedParametersTab, 'numeric');
            app.EOP5.Value = 2;
            app.EOP6EditFieldLabel = uilabel(app.AdvancedParametersTab);
            app.EOP6EditFieldLabel.Text = 'EOP6';
            app.EOP6 = uieditfield(app.AdvancedParametersTab, 'numeric');
            app.EOP6.Value = 9;
            app.EOP7EditFieldLabel = uilabel(app.AdvancedParametersTab);
            app.EOP7EditFieldLabel.Text = 'EOP7';
            app.EOP7 = uieditfield(app.AdvancedParametersTab, 'numeric');
            app.EOP7.Value = 6;
            app.Label_18 = uilabel(app.AdvancedParametersTab);
            app.Label_18.Text = 'Sets the minimum length of a pulse section within a fiber ';
            app.EOP10Label = uilabel(app.AdvancedParametersTab);
            app.EOP10Label.Text = 'EOP10';
            app.EOP10 = uieditfield(app.AdvancedParametersTab, 'numeric');
            app.EOP10.Value = 6;
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_FiberQ

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end